classdef realCalculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        TextArea       matlab.ui.control.TextArea
        TextAreaLabel  matlab.ui.control.Label
        Button_14      matlab.ui.control.Button
        Button_13      matlab.ui.control.Button
        Button_12      matlab.ui.control.Button
        Button_11      matlab.ui.control.Button
        clearButton    matlab.ui.control.Button
        Button_9       matlab.ui.control.Button
        Button_8       matlab.ui.control.Button
        Button_7       matlab.ui.control.Button
        Button_6       matlab.ui.control.Button
        Button_5       matlab.ui.control.Button
        Button_4       matlab.ui.control.Button
        Button_3       matlab.ui.control.Button
        Button_2       matlab.ui.control.Button
        Button         matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button, Button_11, Button_12, Button_13, 
        % Button_2, Button_3, Button_4, Button_5, Button_6, Button_7, 
        % Button_8, Button_9
        function ButtonPushed(app, event)
            buttonValue = event.Source.Text;
            app.TextArea.Value = [app.TextArea.Value{1} buttonValue];
        end

        % Button pushed function: clearButton
        function clearButtonPushed(app, event)
            app.TextArea.Value = '';
        end

        % Button pushed function: Button_14
        function Button_14Pushed(app, event)
            app.TextArea.Value = [app.TextArea.Value{1} event.Source.Text ...
                num2str(eval(app.TextArea.Value{1}))];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '1';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_3.Text = '4';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_4.Text = '5';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_5.Text = '6';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_6.Text = '3';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_7.Text = '7';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_8.Text = '8';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_9.Text = '9';
            app.clearButton = uibutton(app.UIFigure, 'push');
            app.clearButton.ButtonPushedFcn = createCallbackFcn(app, @clearButtonPushed, true);
            app.clearButton.Text = 'clear';
            app.Button_11 = uibutton(app.UIFigure, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_11.Text = '+';
            app.Button_12 = uibutton(app.UIFigure, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_12.Text = '-';
            app.Button_13 = uibutton(app.UIFigure, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_13.Text = '0';
            app.Button_14 = uibutton(app.UIFigure, 'push');
            app.Button_14.ButtonPushedFcn = createCallbackFcn(app, @Button_14Pushed, true);
            app.Button_14.Text = '=';
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = realCalculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end