classdef calculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        operatorDropDown        matlab.ui.control.DropDown
        operatorDropDownLabel   matlab.ui.control.Label
        Label                   matlab.ui.control.Label
        ValueTwoEditField       matlab.ui.control.NumericEditField
        ValueTwoEditFieldLabel  matlab.ui.control.Label
        ValueOneEditField       matlab.ui.control.NumericEditField
        ValueOneEditFieldLabel  matlab.ui.control.Label
        Button                  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            value1 = app.ValueOneEditField.Value; % .Value is needed to get the value
            value2 = app.ValueTwoEditField.Value;
            operator = app.operatorDropDown.Value;
            switch operator
                case '/'
                    value3 = value1 / value2;
                case '+'
                    value3 = value1 + value2;
                case '-'
                    value3 = value1 - value2;
                case '*'
                    value3 = value1 * value2;
        end
%             value3 = value1 + value2;
            app.Label.Text = string(value3); % .Text is needed to print to a label
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.ValueOneEditFieldLabel = uilabel(app.UIFigure);
            app.ValueOneEditFieldLabel.Text = 'Value One';
            app.ValueOneEditField = uieditfield(app.UIFigure, 'numeric');
            app.ValueTwoEditFieldLabel = uilabel(app.UIFigure);
            app.ValueTwoEditFieldLabel.Text = 'Value Two';
            app.ValueTwoEditField = uieditfield(app.UIFigure, 'numeric');
            app.Label = uilabel(app.UIFigure);
            app.operatorDropDownLabel = uilabel(app.UIFigure);
            app.operatorDropDownLabel.Text = 'operator';
            app.operatorDropDown = uidropdown(app.UIFigure);
            app.operatorDropDown.Items = {'+', '-', '/', '*'};
            app.operatorDropDown.Value = '+';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = calculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end